classdef WgControlPanel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaveformGeneratorControlPanelUIFigure  matlab.ui.Figure
        ResetButton           matlab.ui.control.Button
        DisconnectButton      matlab.ui.control.Button
        ChannelsPanel         matlab.ui.container.Panel
        ChannelGrid           matlab.ui.container.GridLayout
        UploadButton          matlab.ui.control.Button
        TriggerButton         matlab.ui.control.Button
        MaxSamplingRateLabel  matlab.ui.control.Label
        MemoryLabel           matlab.ui.control.Label
        ResourceNameLabel     matlab.ui.control.Label
        ModelLabel            matlab.ui.control.Label
        ManufacturerLabel     matlab.ui.control.Label
        NameLabel             matlab.ui.control.Label
    end


    properties (Access = private)
        WaveformLibrary WaveformList
        WaveformGeneratorSetting table
    end

    properties (Access = public)
        SrcApp HardwareControlPanel
        WaveformGenerator
        Name string
        NColumn
        NRow
    end

    properties (Constant)
        PropertyList = ["IsOutput","SamplingRate","TriggerSource","TriggerSlope","OutputMode","OutputLoad","WaveformListName"]
    end

    methods (Access = private)


        function saveWgSetting(app)
            if isempty(app.SrcApp)
                WaveformGeneratorSetting = app.WaveformGeneratorSetting;
                save(which("WaveformGeneratorSetting.mat"),"WaveformGeneratorSetting");
            else
                app.updateSrc;
            end
        end

        function updateSrc(app)
            if ~isempty(app.SrcApp)
                app.SrcApp.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:) = ...
                    app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:);
            end
        end

        function disableProperty(app)
            dP = app.WaveformGenerator.DisabledProperty;
            appProp = string(properties(app));
            for ii = 1:numel(dP)
                targetAppProp = appProp(contains(appProp,dP(ii)));
                for jj = 1:numel(targetAppProp)
                    try
                        app.(targetAppProp(jj)).Enable = "off";
                    catch
                    end
                end
            end
            for ii = 1:numel(dP)
                row = find(app.PropertyList == dP(ii)) + 1;
                if ~isempty(row)
                    for jj = 1:app.NColumn
                        obj = findGridChild(app.ChannelGrid,row,jj+1);
                        try
                            obj.Enable = "off";
                        catch
                        end
                    end
                end
            end
        end
    end

    methods (Access = public)

        function channelWfDropDownValueChanged(app,src,~,cNumber)
            name = string(src.Value);
            if name ~= "None"
                if ~isempty(app.SrcApp)
                    app.WaveformLibrary = app.SrcApp.WaveformLibrary;
                end
                app.WaveformGenerator.WaveformList{cNumber} = app.WaveformLibrary([app.WaveformLibrary.Name] == name);
            else
                app.WaveformGenerator.WaveformList{cNumber} = [];
            end
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).WaveformListName{1}(cNumber) ...
                = name;
            app.updateSrc;
        end

        function uiValueChanged(app,src,~,cNumber)
            value = src.Value;
            if ischar(value)
                value = string(value);
            end
            row = src.Layout.Row;
            propName = app.PropertyList(row-1);
            app.WaveformGenerator.(propName)(cNumber) = value;
            app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == app.Name,:).(propName){1}(cNumber) ...
                = value;
            app.updateSrc;
        end


        function disconnect(app)
            if ~isempty(app.WaveformGenerator)
                app.WaveformGenerator.close
            end
            delete(app)
        end

        function upload(app)
            if ~isempty(app.SrcApp)
                app.WaveformLibrary = app.SrcApp.WaveformLibrary;
            else
                app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
            end

            for ii = 1:app.WaveformGenerator.NChannel
                row = find(app.PropertyList == "WaveformListName") + 1;
                column = ii + 1;
                dd = findGridChild(app.ChannelGrid,row,column);
                name = string(dd.Value);
                if name ~= "None"
                    app.WaveformGenerator.WaveformList{ii} = app.WaveformLibrary([app.WaveformLibrary.Name] == name);
                else
                    app.WaveformGenerator.WaveformList{ii} = [];
                end
            end

            if ~isempty(app.SrcApp)
                varList = app.SrcApp.CurrentVariableList;
                for ii = 1:app.WaveformGenerator.NChannel
                    if ~isempty(app.WaveformGenerator.WaveformList{ii})
                        for jj = 1:numel(app.WaveformGenerator.WaveformList{ii}.WaveformOrigin)
                            wfo = app.WaveformGenerator.WaveformList{ii}.WaveformOrigin{jj};
                            scan = wfo.Scan;
                            for kk = 1:size(scan,1)
                                if ismember(scan.VariableName(kk),varList.Name)
                                    wfo.(scan.ParameterName(kk)) = varList(varList.Name == scan.VariableName(kk),:).Value;
                                end
                            end
                            if isa(wfo,"ModulatedWaveform")
                                modName = ["AmplitudeModulation","FrequencyModulation","PhaseModulation"];
                                for mm = 1:numel(modName)
                                    if ~isempty(wfo.(modName(mm)))
                                        for ll = 1:numel(wfo.(modName(mm)).WaveformOrigin)
                                            wfoMod = wfo.(modName(mm)).WaveformOrigin{ll};
                                            scan = wfoMod.Scan;
                                            for kk = 1:size(scan,1)
                                                if ismember(scan.VariableName(kk),varList.Name)
                                                    wfoMod.(scan.ParameterName(kk)) = varList(varList.Name == scan.VariableName(kk),:).Value;
                                                end
                                            end
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
            end
            app.WaveformGenerator.set
            app.WaveformGenerator.upload
        end

        function disableButton(app)
            app.ResetButton.Enable = "off";
            app.UploadButton.Enable = "off";
            app.DisconnectButton.Enable = "off";
            child = app.ChannelGrid.Children((app.NRow + 1) :end);
            for ii = 1:numel(child)
                child(ii).Enable = "off";
            end
        end

        function enableButton(app)
            app.ResetButton.Enable = "on";
            if isempty(app.SrcApp)
                app.UploadButton.Enable = "on";
                app.DisconnectButton.Enable = "on";
            end
            child = app.ChannelGrid.Children((app.NRow + 1) :end);
            for ii = 1:numel(child)
                child(ii).Enable = "on";
            end
            app.disableProperty;
        end
        
        function setChannelWaveformList(app,chNumber,wflName)
            row = find("IsOutput" == app.PropertyList) + 1;
            column = chNumber + 1;
            obj = findGridChild(app.ChannelGrid,row,column);
            if ~obj.Value
                obj.Value = true;
                app.uiValueChanged(obj,[],chNumber);
            end
            row = find("WaveformListName" == app.PropertyList) + 1;
            obj = findGridChild(app.ChannelGrid,row,column);
            if obj.Value ~= wflName
                obj.Value = wflName;
                app.channelWfDropDownValueChanged(obj,[],chNumber);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, name, srcApp)
            if nargin == 3
                app.SrcApp = srcApp;
                app.WaveformGeneratorSetting = srcApp.WaveformGeneratorSetting;
                app.WaveformLibrary = srcApp.WaveformLibrary;
                app.DisconnectButton.Enable = 'off';
                app.UploadButton.Enable = 'off';
            else
                app.WaveformGeneratorSetting = loadVar("WaveformGeneratorSetting.mat","WaveformGeneratorSetting");
                app.WaveformLibrary = loadVar("WaveformLibrary.mat","WaveformLibrary");
            end

            %% Load setting
            app.WaveformGenerator = getWg(name,false);
            app.WaveformGenerator.connect
            app.Name = app.WaveformGenerator.Name;
            maxSr = app.WaveformGenerator.SamplingRate(1);
            app.MaxSamplingRateLabel.Text = app.MaxSamplingRateLabel.Text + string(maxSr);
            setting = app.WaveformGeneratorSetting(app.WaveformGeneratorSetting.Name == name,:);
            if isempty(setting)
                % Assign default settings to the AWG
                dm = (app.WaveformGenerator.Manufacturer + app.WaveformGenerator.Model);
                t = app.WaveformGeneratorSetting([app.WaveformGeneratorSetting.DeviceModel] == dm,:);
                if ~isempty(t)
                    setting = t(1,:);
                else
                    warning("Can not find saved settings for " + dm + ". Will assign saved settings from another device.")
                    setting = app.WaveformGeneratorSetting(1,:);
                    setting.DeviceModel = dm;
                end
                setting.Name = app.WaveformGenerator.Name;
                app.WaveformGeneratorSetting(end+1,:) = setting;
                if ~isempty(app.SrcApp)
                    app.SrcApp.WaveformGeneratorSetting = app.WaveformGeneratorSetting;
                end
            end
            app.WaveformGenerator.SamplingRate = setting.SamplingRate{1};
            app.WaveformGenerator.TriggerSource = setting.TriggerSource{1};
            app.WaveformGenerator.TriggerSlope = setting.TriggerSlope{1};
            app.WaveformGenerator.OutputMode = setting.OutputMode{1};
            app.WaveformGenerator.IsOutput = setting.IsOutput{1};
            app.WaveformGenerator.OutputLoad = setting.OutputLoad{1};

            %% Display labels
            app.NameLabel.Text = app.NameLabel.Text + app.Name;
            app.ManufacturerLabel.Text = app.ManufacturerLabel.Text + app.WaveformGenerator.Manufacturer;
            app.ModelLabel.Text = app.ModelLabel.Text + app.WaveformGenerator.Model;
            app.ResourceNameLabel.Text = app.ResourceNameLabel.Text + app.WaveformGenerator.ResourceName;
            app.MemoryLabel.Text = app.MemoryLabel.Text + string(app.WaveformGenerator.Memory);
            
            %% Create UI components
            wflName = [app.WaveformLibrary.Name];
            app.NRow = numel(app.PropertyList);
            app.NColumn = app.WaveformGenerator.NChannel;
            app.ChannelGrid.RowHeight = repmat(25,1,app.NRow + 1);
            for ii = 1:app.NRow
                ul = uilabel(app.ChannelGrid,"Text",app.PropertyList(ii));
                ul.Layout.Column = 1;
                ul.Layout.Row = ii+1;
            end
            for ii = 1:app.WaveformGenerator.NChannel
                app.ChannelGrid.ColumnWidth{end + 1} = 150;

                uil = uilabel(app.ChannelGrid,"Text","Ch" + num2str(ii),'HorizontalAlignment','Center');
                uil.Layout.Column = ii + 1;
                uil.Layout.Row = 1;

                uis = uiswitch(app.ChannelGrid, 'slider','ItemsData',[0,1],'Value',app.WaveformGenerator.IsOutput(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uis.Layout.Column = ii + 1;
                uis.Layout.Row = 2;

                uie = uieditfield(app.ChannelGrid,'numeric','Limits',[1,maxSr],'Value',app.WaveformGenerator.SamplingRate(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uie.Layout.Column = ii + 1;
                uie.Layout.Row = 3;
    
                uidd1 = uidropdown(app.ChannelGrid,"Items",["External","Software","Immediate"],'Value',app.WaveformGenerator.TriggerSource(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd1.Layout.Column = ii + 1;
                uidd1.Layout.Row = 4;
                
                uidd2 = uidropdown(app.ChannelGrid,"Items",["Rise","Fall"],'Value',app.WaveformGenerator.TriggerSlope(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd2.Layout.Column = ii + 1;
                uidd2.Layout.Row = 5;

                uidd3 = uidropdown(app.ChannelGrid,"Items",["Normal","Gated"],'Value',app.WaveformGenerator.OutputMode(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd3.Layout.Column = ii + 1;
                uidd3.Layout.Row = 6;

                uidd4 = uidropdown(app.ChannelGrid,"Items",["50","Infinity"],'Value',app.WaveformGenerator.OutputLoad(ii),...
                    'ValueChangedFcn',{@app.uiValueChanged,ii});
                uidd4.Layout.Column = ii + 1;
                uidd4.Layout.Row = 7;

                uidd5 = uidropdown(app.ChannelGrid,"Items",["None",wflName],"ValueChangedFcn",{@app.channelWfDropDownValueChanged,ii});
                uidd5.Layout.Column = ii + 1;
                uidd5.Layout.Row = 8;

                if ~isempty(setting.WaveformListName{1})
                    if ismember(setting.WaveformListName{1},string(uidd5.Items))
                        uidd5.Value = setting.WaveformListName{1}(ii);
                    else
                        uidd5.Value = "None";
                    end
                else
                    uidd5.Value = "None";
                end

                app.channelWfDropDownValueChanged(uidd5,1,ii)
            end

            %% Diable properties
            app.disableProperty;
        end

        % Close request function: WaveformGeneratorControlPanelUIFigure
        function WaveformGeneratorControlPanelUIFigureCloseRequest(app, event)
            app.saveWgSetting;
            if ~isempty(app.SrcApp)
                app.WaveformGeneratorControlPanelUIFigure.Visible = 'off';
            else
                app.disconnect
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.saveWgSetting;
            app.disconnect
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.upload;
            app.saveWgSetting;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.WaveformGenerator.set;
        end

        % Button pushed function: TriggerButton
        function TriggerButtonPushed(app, event)
            if ismethod(app.WaveformGenerator,"trigger")
                app.WaveformGenerator.trigger
            else
                warning("Software trigger is not supported for this device.")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaveformGeneratorControlPanelUIFigure = uifigure('Visible', 'off');
            app.WaveformGeneratorControlPanelUIFigure.Name = 'Waveform Generator Control Panel';
            app.WaveformGeneratorControlPanelUIFigure.CloseRequestFcn = createCallbackFcn(app, @WaveformGeneratorControlPanelUIFigureCloseRequest, true);
            app.WaveformGeneratorControlPanelUIFigure.Tag = 'WgControlPanel';
            app.NameLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.NameLabel.Text = 'Name: ';
            app.ManufacturerLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ManufacturerLabel.Text = 'Manufacturer: ';
            app.ModelLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ModelLabel.Text = 'Model: ';
            app.ResourceNameLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.ResourceNameLabel.Text = 'Resource Name: ';
            app.MemoryLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.MemoryLabel.Text = 'Memory: ';
            app.MaxSamplingRateLabel = uilabel(app.WaveformGeneratorControlPanelUIFigure);
            app.MaxSamplingRateLabel.Text = 'Max Sampling Rate: ';
            app.TriggerButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.TriggerButton.ButtonPushedFcn = createCallbackFcn(app, @TriggerButtonPushed, true);
            app.TriggerButton.Text = 'Trigger';
            app.UploadButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.ChannelsPanel = uipanel(app.WaveformGeneratorControlPanelUIFigure);
            app.ChannelsPanel.Title = 'Channels';
            app.ChannelGrid = uigridlayout(app.ChannelsPanel);
            app.DisconnectButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ResetButton = uibutton(app.WaveformGeneratorControlPanelUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.WaveformGeneratorControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WgControlPanel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaveformGeneratorControlPanelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaveformGeneratorControlPanelUIFigure)
        end
    end
end